classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FilteredImageLabel  matlab.ui.control.Label
        ButterworthLabel    matlab.ui.control.Label
        GaussianLabel       matlab.ui.control.Label
        IdealLabel          matlab.ui.control.Label
        UploadButton_5      matlab.ui.control.Button
        UploadButton_4      matlab.ui.control.Button
        UploadButton_3      matlab.ui.control.Button
        UploadButton_2      matlab.ui.control.Button
        NewButton           matlab.ui.control.Button
        UploadButton        matlab.ui.control.Button
        FrequencyDomainImageFilteringLabel  matlab.ui.control.Label
        AfterImageAxes_3    matlab.ui.control.UIAxes
        AfterImageAxes_2    matlab.ui.control.UIAxes
        AfterImageAxes      matlab.ui.control.UIAxes
        BeforeImageAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        oriImage % Original image, meant to be processed
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.BeforeImageAxes.Colormap = gray(256);
            axis(app.BeforeImageAxes, 'image');

            app.MatchImageAxes.Visible = 'off';
            app.MatchImageAxes.Colormap = gray(256);
            axis(app.MatchImageAxes, 'image');
            
            app.AfterImageAxes.Colormap = gray(256);
            axis(app.AfterImageAxes, 'image');

            % Turn off all histogram axes visibility
            app.GrayAxes.Visible = 'off';
            app.GrayAxes_2.Visible = 'off';

            app.RedAxes.Visible = 'off';
            app.GreenAxes.Visible = 'off';
            app.BlueAxes.Visible = 'off';

            app.RedAxes_2.Visible = 'off';
            app.GreenAxes_2.Visible = 'off';
            app.BlueAxes_2.Visible = 'off';

            app.NewButton.Enable = 'off';
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Callback function
        function ContrastButtonPushed(app, event)
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                new_image = enhanceContrastRgb(app,app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                [countR, countG, countB] = rgbHistogram(app, new_image);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                new_image = enhanceContrastBw(app,app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                counts = graylevelHistogram(app, new_image);
                grayShow(app, counts, 2);
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.BeforeImageAxes,ImageFile);
            app.oriImage = ImageFile;

            app.UploadButton.Enable = 'off';
            app.RGBpictureCheckBox.Enable = 'off';
            app.NewButton.Enable = 'on';

            % Show histogram
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                app.RedAxes.Visible = 'on';
                app.GreenAxes.Visible = 'on';
                app.BlueAxes.Visible = 'on';

                % load histogram
                [countR, countG, countB] =rgbHistogram(app, ImageFile);
                rgbShow(app, countR, countG, countB, 1);

            else 
            % image is grayscale
                app.GrayAxes.Visible = 'on';

                % load histogram
                counts = graylevelHistogram(app,ImageFile);
                grayShow(app, counts, 1);
            end
        end

        % Callback function
        function MatchingButtonPushed(app, event)
            % upload photo
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.MatchImageAxes,ImageFile);

            % extract histogram
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                newImage = histSpecification(app, app.oriImage, ImageFile);
                imagesc(app.AfterImageAxes,newImage);
                [countR, countG, countB] = rgbHistogram(app, newImage);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                newImage = histSpecification(app, app.oriImage, ImageFile);
                imagesc(app.AfterImageAxes,newImage);
                counts = graylevelHistogram(app, newImage);
                grayShow(app, counts, 2);
            end  
        end

        % Callback function
        function RGBpictureCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            cla(app.BeforeImageAxes);
            cla(app.AfterImageAxes);
            cla(app.MatchImageAxes);
            app.MatchImageAxes.Visible = 'off';

            cla(app.GrayAxes);
            app.GrayAxes.Visible = 'off';
            cla(app.GrayAxes_2);
            app.GrayAxes_2.Visible = 'off';
            cla(app.RedAxes);
            app.RedAxes.Visible = 'off';
            cla(app.RedAxes_2);
            app.RedAxes_2.Visible = 'off';
            cla(app.GreenAxes);
            app.GreenAxes.Visible = 'off';
            cla(app.GreenAxes_2);
            app.GreenAxes_2.Visible = 'off';
            cla(app.BlueAxes);
            app.BlueAxes.Visible = 'off';
            cla(app.BlueAxes_2);
            app.BlueAxes_2.Visible = 'off';

            app.NewButton.Enable = 'off';
            app.RGBpictureCheckBox.Enable = 'on';
            app.UploadButton.Enable = 'on';
        end

        % Callback function
        function EqualizatonButtonPushed(app, event)
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                new_image(:,:,1) = hisEqualization(app, app.oriImage(:,:,1));
                new_image(:,:,2) = hisEqualization(app, app.oriImage(:,:,2));
                new_image(:,:,3) = hisEqualization(app, app.oriImage(:,:,3));
                imagesc(app.AfterImageAxes,new_image);
                [countR, countG, countB] = rgbHistogram(app, new_image);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                new_image = hisEqualization(app, app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                counts = graylevelHistogram(app, new_image);
                grayShow(app, counts, 2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.BeforeImageAxes = uiaxes(app.UIFigure);
            app.BeforeImageAxes.XTick = [];
            app.BeforeImageAxes.XTickLabel = {'[ ]'};
            app.BeforeImageAxes.YTick = [];
            app.AfterImageAxes = uiaxes(app.UIFigure);
            app.AfterImageAxes.XTick = [];
            app.AfterImageAxes.XTickLabel = {'[ ]'};
            app.AfterImageAxes.YTick = [];
            app.AfterImageAxes_2 = uiaxes(app.UIFigure);
            app.AfterImageAxes_2.XTick = [];
            app.AfterImageAxes_2.XTickLabel = {'[ ]'};
            app.AfterImageAxes_2.YTick = [];
            app.AfterImageAxes_3 = uiaxes(app.UIFigure);
            app.AfterImageAxes_3.XTick = [];
            app.AfterImageAxes_3.XTickLabel = {'[ ]'};
            app.AfterImageAxes_3.YTick = [];
            app.FrequencyDomainImageFilteringLabel = uilabel(app.UIFigure);
            app.FrequencyDomainImageFilteringLabel.Text = 'Frequency Domain Image Filtering';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload image';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New image';
            app.UploadButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadButton_2.Text = 'Smooth';
            app.UploadButton_3 = uibutton(app.UIFigure, 'push');
            app.UploadButton_3.Text = 'Sharpen';
            app.UploadButton_4 = uibutton(app.UIFigure, 'push');
            app.UploadButton_4.Text = 'Brighten';
            app.UploadButton_5 = uibutton(app.UIFigure, 'push');
            app.UploadButton_5.Text = 'Remove noise';
            app.IdealLabel = uilabel(app.UIFigure);
            app.IdealLabel.Text = 'Ideal';
            app.GaussianLabel = uilabel(app.UIFigure);
            app.GaussianLabel.Text = 'Gaussian';
            app.ButterworthLabel = uilabel(app.UIFigure);
            app.ButterworthLabel.Text = 'Butterworth';
            app.FilteredImageLabel = uilabel(app.UIFigure);
            app.FilteredImageLabel.Visible = 'off';
            app.FilteredImageLabel.Text = 'Filtered Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end